classdef Histogrammcreator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        BrowseButton                   matlab.ui.control.Button
        tfFilePath                     matlab.ui.control.EditField
        ChooseafileLabel               matlab.ui.control.Label
        StartButton                    matlab.ui.control.Button
        GammaButtonGroup               matlab.ui.container.ButtonGroup
        CorrectionButton               matlab.ui.control.RadioButton
        OriginalButton                 matlab.ui.control.RadioButton
        correctionValue                matlab.ui.control.NumericEditField
        HistogrammcreatorLabel         matlab.ui.control.Label
        HistogramButtonGroup           matlab.ui.container.ButtonGroup
        NoneButton                     matlab.ui.control.RadioButton
        StretchingButton               matlab.ui.control.RadioButton
        LinearizationButton            matlab.ui.control.RadioButton
        BothButton                     matlab.ui.control.RadioButton
        SummenfilterButtonGroup        matlab.ui.container.ButtonGroup
        normMittelwertButton           matlab.ui.control.RadioButton
        GaussButton                    matlab.ui.control.RadioButton
        NoneButton_2                   matlab.ui.control.RadioButton
        DifferenzenfilterButtonGroup   matlab.ui.container.ButtonGroup
        xAbleitungVorwaertsgradButton  matlab.ui.control.RadioButton
        yAbleitungRueckwaertsgradButton  matlab.ui.control.RadioButton
        LaplaceButton                  matlab.ui.control.RadioButton
        SobelxButton                   matlab.ui.control.RadioButton
        SobelyButton                   matlab.ui.control.RadioButton
        GradbetragnachSobelButton      matlab.ui.control.RadioButton
        CannyEdgeDetectorButton        matlab.ui.control.RadioButton
        NoneButton_3                   matlab.ui.control.RadioButton
        norMittelwertEdit              matlab.ui.control.NumericEditField
        GaussEdit                      matlab.ui.control.NumericEditField
        RightPanel                     matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        Image                          matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
        originalHisto                  matlab.ui.control.UIAxes
        newHisto                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Property % Description
        %grayScaleImageName = 'GrayscaleImage.jpg';
        %processedImageName = 'ProcessedImage.jpg';
        grayScaleImage
        doubleImage
    end
    
    methods (Access = public)
        
        function gray = getGrayScale(~, filepath)
            img = imread(filepath);
            
            R = img(:,:,1);
            G = img(:,:,2);
            B = img(:,:,3);
            
            gray = 0.2999 * R + 0.587 * G + 0.114 * B;
        end
    end
    
    methods (Access = private)
        
        function result = stretching(~, img)
            result = imadjust(img, stretchlim(img));
        end
        
        function result = linearization(~, img)
            result = histeq(img);
        end
        
        function result = gammaCorrection(~, value, img)
            result = imadjust(img, [], [], value);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [filename, path] = uigetfile({'*.jpg';'*.jpeg';'*.png'});
            
            file = fullfile(path, filename);
            
            if(file ~= 0)
                app.tfFilePath.Value = file;
                
                gray = getGrayScale(app, file);
                
                app.grayScaleImage = repmat(gray, 1, 1, 3);               
                app.Image.ImageSource = app.grayScaleImage;

                histogram(app.originalHisto, app.grayScaleImage);
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %if(app.grayScaleImage ~= 0) 
                processedImage = app.grayScaleImage;
                
                if(app.StretchingButton.Value == true)
                    processedImage = stretching(app, processedImage);
                elseif(app.LinearizationButton.Value == true)
                    processedImage = linearization(app, processedImage);
                elseif(app.BothButton.Value == true)
                    processedImage = stretching(app, processedImage);
                    processedImage = linearization(app, processedImage);
                end
                
                if(app.CorrectionButton.Value == true && app.correctionValue.Value ~= 0)
                    corr = app.correctionValue.Value;
                    
                    processedImage = gammaCorrection(app, corr, processedImage);
                end
                
                app.doubleImage = im2double(processedImage);
                
                if(app.normMittelwertButton.Value == true && app.norMittelwertEdit ~= 0) 
                    h = fspecial('average', app.norMittelwertEdit.Value);
                    processedImage = imfilter(app.doubleImage, h);
                elseif(app.GaussButton.Value == true && app.GaussEdit ~= 0)
                    processedImage = imgaussfilt(app.doubleImage, app.GaussEdit.Value);
                end
                
                if(app.xAbleitungVorwaertsgradButton.Value == true)
                    syms x;
                    h = diff(app.grayScaleImage);
                    processedImage = imfilter(app.doubleImage, h);
                elseif(app.yAbleitungRueckwaertsgradButton.Value == true)
                    syms y;
                    h = diff(app.grayScaleImage);
                    processedImage = imfilter(app.doubleImage, h);
                elseif(app.LaplaceButton.Value == true)
                elseif(app.SobelxButton.Value == true)
                elseif(app.SobelyButton.Value == true)
                elseif(app.GradbetragnachSobelButton.Value == true)
                elseif(app.CannyEdgeDetectorButton.Value == true)
                    processedImage = edge(app.doubleImage, 'canny') * 255;
                end
                
                app.Image2.ImageSource = processedImage;
                
                histogram(app.newHisto, processedImage);
            %end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {819, 819};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {231, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.BrowseButton = uibutton(app.LeftPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse...';
            app.tfFilePath = uieditfield(app.LeftPanel, 'text');
            app.ChooseafileLabel = uilabel(app.LeftPanel);
            app.ChooseafileLabel.Text = 'Choose a file:';
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.GammaButtonGroup = uibuttongroup(app.LeftPanel);
            app.GammaButtonGroup.Title = 'Gamma:';
            app.CorrectionButton = uiradiobutton(app.GammaButtonGroup);
            app.CorrectionButton.Text = 'Correction';
            app.OriginalButton = uiradiobutton(app.GammaButtonGroup);
            app.OriginalButton.Text = 'Original';
            app.OriginalButton.Value = true;
            app.correctionValue = uieditfield(app.LeftPanel, 'numeric');
            app.HistogrammcreatorLabel = uilabel(app.LeftPanel);
            app.HistogrammcreatorLabel.Text = 'Histogrammcreator';
            app.HistogramButtonGroup = uibuttongroup(app.LeftPanel);
            app.HistogramButtonGroup.Title = 'Histogram:';
            app.NoneButton = uiradiobutton(app.HistogramButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.StretchingButton = uiradiobutton(app.HistogramButtonGroup);
            app.StretchingButton.Text = 'Stretching';
            app.LinearizationButton = uiradiobutton(app.HistogramButtonGroup);
            app.LinearizationButton.Text = 'Linearization';
            app.BothButton = uiradiobutton(app.HistogramButtonGroup);
            app.BothButton.Text = 'Both';
            app.SummenfilterButtonGroup = uibuttongroup(app.LeftPanel);
            app.SummenfilterButtonGroup.Title = 'Summenfilter:';
            app.normMittelwertButton = uiradiobutton(app.SummenfilterButtonGroup);
            app.normMittelwertButton.Text = 'norm. Mittelwert';
            app.GaussButton = uiradiobutton(app.SummenfilterButtonGroup);
            app.GaussButton.Text = 'Gauss';
            app.NoneButton_2 = uiradiobutton(app.SummenfilterButtonGroup);
            app.NoneButton_2.Text = 'None';
            app.NoneButton_2.Value = true;
            app.DifferenzenfilterButtonGroup = uibuttongroup(app.LeftPanel);
            app.DifferenzenfilterButtonGroup.Title = 'Differenzenfilter:';
            app.xAbleitungVorwaertsgradButton = uiradiobutton(app.DifferenzenfilterButtonGroup);
            app.xAbleitungVorwaertsgradButton.Text = '1. x-Ableitung (Vorwaertsgrad.)';
            app.yAbleitungRueckwaertsgradButton = uiradiobutton(app.DifferenzenfilterButtonGroup);
            app.yAbleitungRueckwaertsgradButton.Text = '1. y-Ableitung (Rueckwaertsgrad.)';
            app.LaplaceButton = uiradiobutton(app.DifferenzenfilterButtonGroup);
            app.LaplaceButton.Text = 'Laplace';
            app.SobelxButton = uiradiobutton(app.DifferenzenfilterButtonGroup);
            app.SobelxButton.Text = 'Sobel (x)';
            app.SobelyButton = uiradiobutton(app.DifferenzenfilterButtonGroup);
            app.SobelyButton.Text = 'Sobel (y)';
            app.GradbetragnachSobelButton = uiradiobutton(app.DifferenzenfilterButtonGroup);
            app.GradbetragnachSobelButton.Text = 'Grad.betrag nach Sobel';
            app.CannyEdgeDetectorButton = uiradiobutton(app.DifferenzenfilterButtonGroup);
            app.CannyEdgeDetectorButton.Text = 'Canny Edge Detector';
            app.NoneButton_3 = uiradiobutton(app.DifferenzenfilterButtonGroup);
            app.NoneButton_3.Text = 'None';
            app.NoneButton_3.Value = true;
            app.norMittelwertEdit = uieditfield(app.LeftPanel, 'numeric');
            app.norMittelwertEdit.ValueDisplayFormat = '%.1f';
            app.GaussEdit = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.Image = uiimage(app.GridLayout2);
            app.Image2 = uiimage(app.GridLayout2);
            app.originalHisto = uiaxes(app.GridLayout2);
            title(app.originalHisto, 'Histogramm')
            xlabel(app.originalHisto, 'Grayscale')
            ylabel(app.originalHisto, 'Frequency')
            zlabel(app.originalHisto, 'Z')
            app.originalHisto.XLim = [0 255];
            app.newHisto = uiaxes(app.GridLayout2);
            title(app.newHisto, 'Histogramm')
            xlabel(app.newHisto, 'Grayscale')
            ylabel(app.newHisto, 'Frequency')
            zlabel(app.newHisto, 'Z')
            app.newHisto.XLim = [0 255];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Histogrammcreator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end